classdef Final_Dice_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FirstRollButton_1       matlab.ui.control.Button
        FirstRollButton_2       matlab.ui.control.Button
        RollAgainButton_1       matlab.ui.control.Button
        RollAgainButton_2       matlab.ui.control.Button
        ResetButton             matlab.ui.control.Button
        ExitButton              matlab.ui.control.Button
        ReturntoMainMenuButton  matlab.ui.control.Button
        Image1                  matlab.ui.control.Image
        Image2                  matlab.ui.control.Image
        Image3                  matlab.ui.control.Image
        Image4                  matlab.ui.control.Image
        Image5                  matlab.ui.control.Image
        Image6                  matlab.ui.control.Image
        LockSwitchLabel         matlab.ui.control.Label
        LockSwitch1             matlab.ui.control.Switch
        LockSwitchLabel_2       matlab.ui.control.Label
        LockSwitch2             matlab.ui.control.Switch
        LockSwitchLabel_3       matlab.ui.control.Label
        LockSwitch3             matlab.ui.control.Switch
        LockSwitchLabel_4       matlab.ui.control.Label
        LockSwitch4             matlab.ui.control.Switch
        LockSwitchLabel_5       matlab.ui.control.Label
        LockSwitch5             matlab.ui.control.Switch
        LockSwitchLabel_6       matlab.ui.control.Label
        LockSwitch6             matlab.ui.control.Switch
        Cover                   matlab.ui.control.Image
        CoverRollAgain_1        matlab.ui.control.Image
        CoverRollAgain_2        matlab.ui.control.Image
    end


    properties (Access = public)
        die1 = 0
        die2 = 0
        die3 = 0
        die4 = 0
        die5 = 0
        die6 = 0
        
        die1_2 = 0
        die2_2 = 0
        die3_2 = 0
        die4_2 = 0
        die5_2 = 0
        die6_2 = 0
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Image1.ImageSource = 'blank.jpg';
            app.Image2.ImageSource = 'blank.jpg';
            app.Image3.ImageSource = 'blank.jpg';
            app.Image4.ImageSource = 'blank.jpg';
            app.Image5.ImageSource = 'blank.jpg';
            app.Image6.ImageSource = 'blank.jpg';
            
            app.LockSwitch1.Value = '0';
            app.LockSwitch2.Value = '0';
            app.LockSwitch3.Value = '0';
            app.LockSwitch4.Value = '0';
            app.LockSwitch5.Value = '0';
            app.LockSwitch6.Value = '0';
            
            app.Cover.Visible = 'on';
            
            app.FirstRollButton_1.Visible = 'on';
            app.FirstRollButton_2.Visible = 'on';
            app.CoverRollAgain_1.Visible = 'on';
            app.CoverRollAgain_2.Visible = 'on'
            
        end

        % Button pushed function: FirstRollButton_1
        function FirstRollButton_1Pushed(app, event)
                    value1 = app.LockSwitch1.Value
                    if value1 == '0'
                        app.die1 = randi(6)
                
                        if app.die1 == 1
                            app.Image1.ImageSource = 'dice1.jpg';
                        elseif app.die1 == 2
                            app.Image1.ImageSource = 'dice2.jpg';
                        elseif app.die1 == 3
                            app.Image1.ImageSource = 'dice3.jpg';
                        elseif app.die1 == 4
                            app.Image1.ImageSource = 'dice4.jpg';
                        elseif app.die1 == 5
                            app.Image1.ImageSource = 'dice5.jpg';
                        elseif app.die1 == 6
                            app.Image1.ImageSource = 'dice6.jpg' 
                        end
                    end


                    value2 = app.LockSwitch2.Value
                    if value2 == '0'
                        app.die2 = randi(6)
                   
        
                        if app.die2 == 1
                            app.Image2.ImageSource = 'dice1.jpg';
                        elseif app.die2 == 2
                            app.Image2.ImageSource = 'dice2.jpg';
                        elseif app.die2 == 3
                            app.Image2.ImageSource = 'dice3.jpg';
                         elseif app.die2 == 4
                            app.Image2.ImageSource = 'dice4.jpg';
                         elseif app.die2 == 5
                            app.Image2.ImageSource = 'dice5.jpg';
                         elseif app.die2 == 6
                            app.Image2.ImageSource = 'dice6.jpg';
                        end
                    end
                        
                        
                    value3 = app.LockSwitch3.Value
                    if value3 == '0'
                        app.die3 = randi(6)
                   
        
                        if app.die3 == 1
                            app.Image3.ImageSource = 'dice1.jpg';
                        elseif app.die3 == 2
                            app.Image3.ImageSource = 'dice2.jpg';
                        elseif app.die3 == 3
                            app.Image3.ImageSource = 'dice3.jpg';
                        elseif app.die3 == 4
                            app.Image3.ImageSource = 'dice4.jpg';
                        elseif app.die3 == 5
                            app.Image3.ImageSource = 'dice5.jpg';
                        elseif app.die3 == 6
                            app.Image3.ImageSource = 'dice6.jpg';
                        end
                    end

                    
                    value4 = app.LockSwitch4.Value
                    if value4 == '0'
                        app.die4 = randi(6)
                   
        
                        if app.die4 == 1
                            app.Image4.ImageSource = 'dice1.jpg';
                        elseif app.die4 == 2
                            app.Image4.ImageSource = 'dice2.jpg';
                        elseif app.die4 == 3
                            app.Image4.ImageSource = 'dice3.jpg';
                        elseif app.die4 == 4
                            app.Image4.ImageSource = 'dice4.jpg';
                        elseif app.die4 == 5
                            app.Image4.ImageSource = 'dice5.jpg';
                        elseif app.die4 == 6
                            app.Image4.ImageSource = 'dice6.jpg';
                        end
                    end


                    value5 = app.LockSwitch5.Value
                    if value5 == '0'
                        app.die5 = randi(6)
                   
        
                        if app.die5 == 1
                            app.Image5.ImageSource = 'dice1.jpg';
                        elseif app.die5 == 2
                            app.Image5.ImageSource = 'dice2.jpg';
                        elseif app.die5 == 3
                            app.Image5.ImageSource = 'dice3.jpg';
                        elseif app.die5 == 4
                            app.Image5.ImageSource = 'dice4.jpg';
                        elseif app.die5 == 5
                            app.Image5.ImageSource = 'dice5.jpg';
                        elseif app.die5 == 6
                            app.Image5.ImageSource = 'dice6.jpg';
                        end
                    end


                    value6 = app.LockSwitch6.Value
                    if value6 == '0'
                        app.die6 = randi(6)
                   
        
                        if app.die6 == 1
                            app.Image6.ImageSource = 'dice1.jpg';
                        elseif app.die6 == 2
                            app.Image6.ImageSource = 'dice2.jpg';
                        elseif app.die6 == 3
                            app.Image6.ImageSource = 'dice3.jpg';
                        elseif app.die6 == 4
                            app.Image6.ImageSource = 'dice4.jpg';
                        elseif app.die6 == 5
                            app.Image6.ImageSource = 'dice5.jpg';
                        elseif app.die6 == 6
                            app.Image6.ImageSource = 'dice6.jpg';
                        end
                    end
                    
                    app.Cover.Visible = 'off'
                    
                    app.FirstRollButton_1.Visible = 'off'
                    app.FirstRollButton_2.Visible = 'off'
                    
                    app.CoverRollAgain_1.Visible = 'off'


        end

        % Button pushed function: FirstRollButton_2
        function FirstRollButton_2Pushed(app, event)
                    value1 = app.LockSwitch1.Value
                    if value1 == '0'
                        app.die1_2 = randi(6)
                
                        if app.die1_2 == 1
                            app.Image1.ImageSource = 'dice1.jpg';
                        elseif app.die1_2 == 2
                            app.Image1.ImageSource = 'dice2.jpg';
                        elseif app.die1_2 == 3
                            app.Image1.ImageSource = 'dice3.jpg';
                        elseif app.die1_2 == 4
                            app.Image1.ImageSource = 'dice4.jpg';
                        elseif app.die1_2 == 5
                            app.Image1.ImageSource = 'dice5.jpg';
                        elseif app.die1_2 == 6
                            app.Image1.ImageSource = 'dice6.jpg' 
                        end
                    end


                    value2 = app.LockSwitch2.Value
                    if value2 == '0'
                        app.die2_2 = randi(6)
                   
        
                        if app.die2_2 == 1
                            app.Image2.ImageSource = 'dice1.jpg';
                        elseif app.die2_2 == 2
                            app.Image2.ImageSource = 'dice2.jpg';
                        elseif app.die2_2 == 3
                            app.Image2.ImageSource = 'dice3.jpg';
                         elseif app.die2_2 == 4
                            app.Image2.ImageSource = 'dice4.jpg';
                         elseif app.die2_2 == 5
                            app.Image2.ImageSource = 'dice5.jpg';
                         elseif app.die2_2 == 6
                            app.Image2.ImageSource = 'dice6.jpg';
                        end
                    end
                        
                        
                    value3 = app.LockSwitch3.Value
                    if value3 == '0'
                        app.die3_2 = randi(6)
                   
        
                        if app.die3_2 == 1
                            app.Image3.ImageSource = 'dice1.jpg';
                        elseif app.die3_2 == 2
                            app.Image3.ImageSource = 'dice2.jpg';
                        elseif app.die3_2 == 3
                            app.Image3.ImageSource = 'dice3.jpg';
                        elseif app.die3_2 == 4
                            app.Image3.ImageSource = 'dice4.jpg';
                        elseif app.die3_2 == 5
                            app.Image3.ImageSource = 'dice5.jpg';
                        elseif app.die3_2 == 6
                            app.Image3.ImageSource = 'dice6.jpg';
                        end
                    end

                    
                    value4 = app.LockSwitch4.Value
                    if value4 == '0'
                        app.die4_2 = randi(6)
                   
        
                        if app.die4_2 == 1
                            app.Image4.ImageSource = 'dice1.jpg';
                        elseif app.die4_2 == 2
                            app.Image4.ImageSource = 'dice2.jpg';
                        elseif app.die4_2 == 3
                            app.Image4.ImageSource = 'dice3.jpg';
                        elseif app.die4_2 == 4
                            app.Image4.ImageSource = 'dice4.jpg';
                        elseif app.die4_2 == 5
                            app.Image4.ImageSource = 'dice5.jpg';
                        elseif app.die4_2 == 6
                            app.Image4.ImageSource = 'dice6.jpg';
                        end
                    end


                    value5 = app.LockSwitch5.Value
                    if value5 == '0'
                        app.die5_2 = randi(6)
                   
        
                        if app.die5_2 == 1
                            app.Image5.ImageSource = 'dice1.jpg';
                        elseif app.die5_2 == 2
                            app.Image5.ImageSource = 'dice2.jpg';
                        elseif app.die5_2 == 3
                            app.Image5.ImageSource = 'dice3.jpg';
                        elseif app.die5_2 == 4
                            app.Image5.ImageSource = 'dice4.jpg';
                        elseif app.die5_2 == 5
                            app.Image5.ImageSource = 'dice5.jpg';
                        elseif app.die5_2 == 6
                            app.Image5.ImageSource = 'dice6.jpg';
                        end
                    end


                    value6 = app.LockSwitch6.Value
                    if value6 == '0'
                        app.die6_2 = randi(6)
                   
        
                        if app.die6_2 == 1
                            app.Image6.ImageSource = 'dice1.jpg';
                        elseif app.die6_2 == 2
                            app.Image6.ImageSource = 'dice2.jpg';
                        elseif app.die6_2 == 3
                            app.Image6.ImageSource = 'dice3.jpg';
                        elseif app.die6_2 == 4
                            app.Image6.ImageSource = 'dice4.jpg';
                        elseif app.die6_2 == 5
                            app.Image6.ImageSource = 'dice5.jpg';
                        elseif app.die6_2 == 6
                            app.Image6.ImageSource = 'dice6.jpg';
                        end
                    end
                    
                    app.Cover.Visible = 'off'
                    
                    app.FirstRollButton_2.Visible = 'off'
                    app.FirstRollButton_1.Visible = 'off'
                    
                    app.CoverRollAgain_2.Visible = 'off'


        end

        % Value changed function: LockSwitch1
        function LockSwitch1ValueChanged(app, event)
            value1 = app.LockSwitch1.Value
            
           
            if value1 == '1'
                app.die1 = 0
                x = app.die1
                if strcmp(app.Image1.ImageSource, 'dice1.jpg')
                    app.Image1.ImageSource = 'dice1.jpg'
                elseif strcmp(app.Image1.ImageSource, 'dice2.jpg')
                    app.Image1.ImageSource = 'dice2.jpg'
                elseif strcmp(app.Image1.ImageSource, 'dice3.jpg')
                    app.Image1.ImageSource = 'dice3.jpg'
                elseif strcmp(app.Image1.ImageSource, 'dice4.jpg')
                    app.Image1.ImageSource = 'dice4.jpg'
                elseif strcmp(app.Image1.ImageSource, 'dice5.jpg')
                    app.Image1.ImageSource = 'dice5.jpg'
                elseif strcmp(app.Image1.ImageSource, 'dice6.jpg')
                    app.Image1.ImageSource = 'dice6.jpg'
                end


            elseif value1 == '0'
                app.die1 = randi(6)
                x = app.die1
            end
            
            
        end

        % Value changed function: LockSwitch2
        function LockSwitch2ValueChanged(app, event)
            value2 = app.LockSwitch2.Value
            
           
            if value2 == '1'
                app.die2 = 0
                x = app.die2
                if strcmp(app.Image2.ImageSource, 'dice1.jpg')
                    app.Image2.ImageSource = 'dice1.jpg'
                elseif strcmp(app.Image2.ImageSource, 'dice2.jpg')
                    app.Image2.ImageSource = 'dice2.jpg'
                elseif strcmp(app.Image2.ImageSource, 'dice3.jpg')
                    app.Image2.ImageSource = 'dice3.jpg'
                elseif strcmp(app.Image2.ImageSource, 'dice4.jpg')
                    app.Image2.ImageSource = 'dice4.jpg'
                elseif strcmp(app.Image2.ImageSource, 'dice5.jpg')
                    app.Image2.ImageSource = 'dice5.jpg'
                elseif strcmp(app.Image2.ImageSource, 'dice6.jpg')
                    app.Image2.ImageSource = 'dice6.jpg'
                end


            elseif value2 == '0'
                app.die2 = randi(6)
                x = app.die2
            end
            
            
        end

        % Value changed function: LockSwitch3
        function LockSwitch3ValueChanged(app, event)
            value3 = app.LockSwitch3.Value
            
           
            if value3 == '1'
                app.die3 = 0
                x = app.die3
                if strcmp(app.Image3.ImageSource, 'dice1.jpg')
                    app.Image3.ImageSource = 'dice1.jpg'
                elseif strcmp(app.Image3.ImageSource, 'dice2.jpg')
                    app.Image3.ImageSource = 'dice2.jpg'
                elseif strcmp(app.Image3.ImageSource, 'dice3.jpg')
                    app.Image3.ImageSource = 'dice3.jpg'
                elseif strcmp(app.Image3.ImageSource, 'dice4.jpg')
                    app.Image3.ImageSource = 'dice4.jpg'
                elseif strcmp(app.Image3.ImageSource, 'dice5.jpg')
                    app.Image3.ImageSource = 'dice5.jpg'
                elseif strcmp(app.Image3.ImageSource, 'dice6.jpg')
                    app.Image3.ImageSource = 'dice6.jpg'
                end


            elseif value3 == '0'
                app.die3 = randi(6)
                x = app.die3
            end
            
            
        end

        % Value changed function: LockSwitch4
        function LockSwitch4ValueChanged(app, event)
            value4 = app.LockSwitch4.Value;

            
            if value4 == '1'
                app.die4 = 0
                x = app.die4
                if strcmp(app.Image4.ImageSource, 'dice1.jpg')
                    app.Image4.ImageSource = 'dice1.jpg'
                elseif strcmp(app.Image4.ImageSource, 'dice2.jpg')
                    app.Image4.ImageSource = 'dice2.jpg'
                elseif strcmp(app.Image4.ImageSource, 'dice3.jpg')
                    app.Image4.ImageSource = 'dice3.jpg'
                elseif strcmp(app.Image4.ImageSource, 'dice4.jpg')
                    app.Image4.ImageSource = 'dice4.jpg'
                elseif strcmp(app.Image4.ImageSource, 'dice5.jpg')
                    app.Image4.ImageSource = 'dice5.jpg'
                elseif strcmp(app.Image4.ImageSource, 'dice6.jpg')
                    app.Image4.ImageSource = 'dice6.jpg'
                end


            elseif value4 == '0'
                app.die4 = randi(6)
                x = app.die4
            end
            
             
        end

        % Value changed function: LockSwitch5
        function LockSwitch5ValueChanged(app, event)
            value5 = app.LockSwitch5.Value;
            
            
            if value5 == '1'
                app.die5 = 0
                x = app.die5
                if strcmp(app.Image5.ImageSource, 'dice1.jpg')
                    app.Image5.ImageSource = 'dice1.jpg'
                elseif strcmp(app.Image5.ImageSource, 'dice2.jpg')
                    app.Image5.ImageSource = 'dice2.jpg'
                elseif strcmp(app.Image5.ImageSource, 'dice3.jpg')
                    app.Image5.ImageSource = 'dice3.jpg'
                elseif strcmp(app.Image5.ImageSource, 'dice4.jpg')
                    app.Image5.ImageSource = 'dice4.jpg'
                elseif strcmp(app.Image5.ImageSource, 'dice5.jpg')
                    app.Image5.ImageSource = 'dice5.jpg'
                elseif strcmp(app.Image5.ImageSource, 'dice6.jpg')
                    app.Image5.ImageSource = 'dice6.jpg'
                end


            elseif value5 == '0'
                app.die5 = randi(6)
                x = app.die5
            end


        end

        % Value changed function: LockSwitch6
        function LockSwitch6ValueChanged(app, event)
            value6 = app.LockSwitch6.Value;


            if value6 == '1'
                app.die6 = 0
                x = app.die6
                if strcmp(app.Image6.ImageSource, 'dice1.jpg')
                    app.Image6.ImageSource = 'dice1.jpg'
                elseif strcmp(app.Image6.ImageSource, 'dice2.jpg')
                    app.Image6.ImageSource = 'dice2.jpg'
                elseif strcmp(app.Image6.ImageSource, 'dice3.jpg')
                    app.Image6.ImageSource = 'dice3.jpg'
                elseif strcmp(app.Image6.ImageSource, 'dice4.jpg')
                    app.Image6.ImageSource = 'dice4.jpg'
                elseif strcmp(app.Image6.ImageSource, 'dice5.jpg')
                    app.Image6.ImageSource = 'dice5.jpg'
                elseif strcmp(app.Image6.ImageSource, 'dice6.jpg')
                    app.Image6.ImageSource = 'dice6.jpg'
                end


            elseif value6 == '0'
                app.die6 = randi(6)
                x = app.die6
            end
            
                             
        end

        % Button pushed function: RollAgainButton_1
        function RollAgainButton_1Pushed(app, event)

                    value1 = app.LockSwitch1.Value
                    if value1 == '0'
                        app.die1 = randi(6)
                
                        if app.die1 == 1
                            app.Image1.ImageSource = 'dice1.jpg';
                        elseif app.die1 == 2
                            app.Image1.ImageSource = 'dice2.jpg';
                        elseif app.die1 == 3
                            app.Image1.ImageSource = 'dice3.jpg';
                        elseif app.die1 == 4
                            app.Image1.ImageSource = 'dice4.jpg';
                        elseif app.die1 == 5
                            app.Image1.ImageSource = 'dice5.jpg';
                        elseif app.die1 == 6
                            app.Image1.ImageSource = 'dice6.jpg' 
                        end
                    end


                    value2 = app.LockSwitch2.Value
                    if value2 == '0'
                        app.die2 = randi(6)
                   
        
                        if app.die2 == 1
                            app.Image2.ImageSource = 'dice1.jpg';
                        elseif app.die2 == 2
                            app.Image2.ImageSource = 'dice2.jpg';
                        elseif app.die2 == 3
                            app.Image2.ImageSource = 'dice3.jpg';
                         elseif app.die2 == 4
                            app.Image2.ImageSource = 'dice4.jpg';
                         elseif app.die2 == 5
                            app.Image2.ImageSource = 'dice5.jpg';
                         elseif app.die2 == 6
                            app.Image2.ImageSource = 'dice6.jpg';
                        end
                    end
                        
                        
                    value3 = app.LockSwitch3.Value
                    if value3 == '0'
                        app.die3 = randi(6)
                   
        
                        if app.die3 == 1
                            app.Image3.ImageSource = 'dice1.jpg';
                        elseif app.die3 == 2
                            app.Image3.ImageSource = 'dice2.jpg';
                        elseif app.die3 == 3
                            app.Image3.ImageSource = 'dice3.jpg';
                        elseif app.die3 == 4
                            app.Image3.ImageSource = 'dice4.jpg';
                        elseif app.die3 == 5
                            app.Image3.ImageSource = 'dice5.jpg';
                        elseif app.die3 == 6
                            app.Image3.ImageSource = 'dice6.jpg';
                        end
                    end

                    
                    value4 = app.LockSwitch4.Value
                    if value4 == '0'
                        app.die4 = randi(6)
                   
        
                        if app.die4 == 1
                            app.Image4.ImageSource = 'dice1.jpg';
                        elseif app.die4 == 2
                            app.Image4.ImageSource = 'dice2.jpg';
                        elseif app.die4 == 3
                            app.Image4.ImageSource = 'dice3.jpg';
                        elseif app.die4 == 4
                            app.Image4.ImageSource = 'dice4.jpg';
                        elseif app.die4 == 5
                            app.Image4.ImageSource = 'dice5.jpg';
                        elseif app.die4 == 6
                            app.Image4.ImageSource = 'dice6.jpg';
                        end
                    end


                    value5 = app.LockSwitch5.Value
                    if value5 == '0'
                        app.die5 = randi(6)
                   
        
                        if app.die5 == 1
                            app.Image5.ImageSource = 'dice1.jpg';
                        elseif app.die5 == 2
                            app.Image5.ImageSource = 'dice2.jpg';
                        elseif app.die5 == 3
                            app.Image5.ImageSource = 'dice3.jpg';
                        elseif app.die5 == 4
                            app.Image5.ImageSource = 'dice4.jpg';
                        elseif app.die5 == 5
                            app.Image5.ImageSource = 'dice5.jpg';
                        elseif app.die5 == 6
                            app.Image5.ImageSource = 'dice6.jpg';
                        end
                    end


                    value6 = app.LockSwitch6.Value
                    if value6 == '0'
                        app.die6 = randi(6)
                   
        
                        if app.die6 == 1
                            app.Image6.ImageSource = 'dice1.jpg';
                        elseif app.die6 == 2
                            app.Image6.ImageSource = 'dice2.jpg';
                        elseif app.die6 == 3
                            app.Image6.ImageSource = 'dice3.jpg';
                        elseif app.die6 == 4
                            app.Image6.ImageSource = 'dice4.jpg';
                        elseif app.die6 == 5
                            app.Image6.ImageSource = 'dice5.jpg';
                        elseif app.die6 == 6
                            app.Image6.ImageSource = 'dice6.jpg';
                        end
                    end


        end

        % Button pushed function: RollAgainButton_2
        function RollAgainButton_2Pushed(app, event)
                    value1 = app.LockSwitch1.Value
                    if value1 == '0'
                        app.die1_2 = randi(6)
                
                        if app.die1_2 == 1
                            app.Image1.ImageSource = 'dice1.jpg';
                        elseif app.die1_2 == 2
                            app.Image1.ImageSource = 'dice2.jpg';
                        elseif app.die1_2 == 3
                            app.Image1.ImageSource = 'dice3.jpg';
                        elseif app.die1_2 == 4
                            app.Image1.ImageSource = 'dice4.jpg';
                        elseif app.die1_2 == 5
                            app.Image1.ImageSource = 'dice5.jpg';
                        elseif app.die1_2 == 6
                            app.Image1.ImageSource = 'dice6.jpg' 
                        end
                    end


                    value2 = app.LockSwitch2.Value
                    if value2 == '0'
                        app.die2_2 = randi(6)
                   
        
                        if app.die2_2 == 1
                            app.Image2.ImageSource = 'dice1.jpg';
                        elseif app.die2_2 == 2
                            app.Image2.ImageSource = 'dice2.jpg';
                        elseif app.die2_2 == 3
                            app.Image2.ImageSource = 'dice3.jpg';
                         elseif app.die2_2 == 4
                            app.Image2.ImageSource = 'dice4.jpg';
                         elseif app.die2_2 == 5
                            app.Image2.ImageSource = 'dice5.jpg';
                         elseif app.die2_2 == 6
                            app.Image2.ImageSource = 'dice6.jpg';
                        end
                    end
                        
                        
                    value3 = app.LockSwitch3.Value
                    if value3 == '0'
                        app.die3_2 = randi(6)
                   
        
                        if app.die3_2 == 1
                            app.Image3.ImageSource = 'dice1.jpg';
                        elseif app.die3_2 == 2
                            app.Image3.ImageSource = 'dice2.jpg';
                        elseif app.die3_2 == 3
                            app.Image3.ImageSource = 'dice3.jpg';
                        elseif app.die3_2 == 4
                            app.Image3.ImageSource = 'dice4.jpg';
                        elseif app.die3_2 == 5
                            app.Image3.ImageSource = 'dice5.jpg';
                        elseif app.die3_2 == 6
                            app.Image3.ImageSource = 'dice6.jpg';
                        end
                    end

                    
                    value4 = app.LockSwitch4.Value
                    if value4 == '0'
                        app.die4_2 = randi(6)
                   
        
                        if app.die4_2 == 1
                            app.Image4.ImageSource = 'dice1.jpg';
                        elseif app.die4_2 == 2
                            app.Image4.ImageSource = 'dice2.jpg';
                        elseif app.die4_2 == 3
                            app.Image4.ImageSource = 'dice3.jpg';
                        elseif app.die4_2 == 4
                            app.Image4.ImageSource = 'dice4.jpg';
                        elseif app.die4_2 == 5
                            app.Image4.ImageSource = 'dice5.jpg';
                        elseif app.die4_2 == 6
                            app.Image4.ImageSource = 'dice6.jpg';
                        end
                    end


                    value5 = app.LockSwitch5.Value
                    if value5 == '0'
                        app.die5_2 = randi(6)
                   
        
                        if app.die5_2 == 1
                            app.Image5.ImageSource = 'dice1.jpg';
                        elseif app.die5_2 == 2
                            app.Image5.ImageSource = 'dice2.jpg';
                        elseif app.die5_2 == 3
                            app.Image5.ImageSource = 'dice3.jpg';
                        elseif app.die5_2 == 4
                            app.Image5.ImageSource = 'dice4.jpg';
                        elseif app.die5_2 == 5
                            app.Image5.ImageSource = 'dice5.jpg';
                        elseif app.die5_2 == 6
                            app.Image5.ImageSource = 'dice6.jpg';
                        end
                    end


                    value6 = app.LockSwitch6.Value
                    if value6 == '0'
                        app.die6_2 = randi(6)
                   
        
                        if app.die6_2 == 1
                            app.Image6.ImageSource = 'dice1.jpg';
                        elseif app.die6_2 == 2
                            app.Image6.ImageSource = 'dice2.jpg';
                        elseif app.die6_2 == 3
                            app.Image6.ImageSource = 'dice3.jpg';
                        elseif app.die6_2 == 4
                            app.Image6.ImageSource = 'dice4.jpg';
                        elseif app.die6_2 == 5
                            app.Image6.ImageSource = 'dice5.jpg';
                        elseif app.die6_2 == 6
                            app.Image6.ImageSource = 'dice6.jpg';
                        end
                    end
                    
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FirstRollButton_1 = uibutton(app.UIFigure, 'push');
            app.FirstRollButton_1.ButtonPushedFcn = createCallbackFcn(app, @FirstRollButton_1Pushed, true);
            app.FirstRollButton_1.Text = 'First Roll';
            app.FirstRollButton_2 = uibutton(app.UIFigure, 'push');
            app.FirstRollButton_2.ButtonPushedFcn = createCallbackFcn(app, @FirstRollButton_2Pushed, true);
            app.FirstRollButton_2.Text = 'First Roll';
            app.RollAgainButton_1 = uibutton(app.UIFigure, 'push');
            app.RollAgainButton_1.ButtonPushedFcn = createCallbackFcn(app, @RollAgainButton_1Pushed, true);
            app.RollAgainButton_1.Text = 'Roll Again';
            app.RollAgainButton_2 = uibutton(app.UIFigure, 'push');
            app.RollAgainButton_2.ButtonPushedFcn = createCallbackFcn(app, @RollAgainButton_2Pushed, true);
            app.RollAgainButton_2.Text = 'Roll Again';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.Text = 'Exit';
            app.ReturntoMainMenuButton = uibutton(app.UIFigure, 'push');
            app.ReturntoMainMenuButton.Text = {'Return to'; 'Main Menu'};
            app.Image1 = uiimage(app.UIFigure);
            app.Image1.ImageSource = 'blank.jpg';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'blank.jpg';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'blank.jpg';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = 'blank.jpg';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = 'blank.jpg';
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageSource = 'blank.jpg';
            app.LockSwitchLabel = uilabel(app.UIFigure);
            app.LockSwitchLabel.Text = 'Lock';
            app.LockSwitch1 = uiswitch(app.UIFigure, 'slider');
            app.LockSwitch1.Items = {'0', '1'};
            app.LockSwitch1.ValueChangedFcn = createCallbackFcn(app, @LockSwitch1ValueChanged, true);
            app.LockSwitch1.Value = '0';
            app.LockSwitchLabel_2 = uilabel(app.UIFigure);
            app.LockSwitchLabel_2.Text = 'Lock';
            app.LockSwitch2 = uiswitch(app.UIFigure, 'slider');
            app.LockSwitch2.Items = {'0', '1'};
            app.LockSwitch2.ValueChangedFcn = createCallbackFcn(app, @LockSwitch2ValueChanged, true);
            app.LockSwitch2.Value = '0';
            app.LockSwitchLabel_3 = uilabel(app.UIFigure);
            app.LockSwitchLabel_3.Text = 'Lock';
            app.LockSwitch3 = uiswitch(app.UIFigure, 'slider');
            app.LockSwitch3.Items = {'0', '1'};
            app.LockSwitch3.ValueChangedFcn = createCallbackFcn(app, @LockSwitch3ValueChanged, true);
            app.LockSwitch3.Value = '0';
            app.LockSwitchLabel_4 = uilabel(app.UIFigure);
            app.LockSwitchLabel_4.Text = 'Lock';
            app.LockSwitch4 = uiswitch(app.UIFigure, 'slider');
            app.LockSwitch4.Items = {'0', '1'};
            app.LockSwitch4.ValueChangedFcn = createCallbackFcn(app, @LockSwitch4ValueChanged, true);
            app.LockSwitch4.Value = '0';
            app.LockSwitchLabel_5 = uilabel(app.UIFigure);
            app.LockSwitchLabel_5.Text = 'Lock';
            app.LockSwitch5 = uiswitch(app.UIFigure, 'slider');
            app.LockSwitch5.Items = {'0', '1'};
            app.LockSwitch5.ValueChangedFcn = createCallbackFcn(app, @LockSwitch5ValueChanged, true);
            app.LockSwitch5.Value = '0';
            app.LockSwitchLabel_6 = uilabel(app.UIFigure);
            app.LockSwitchLabel_6.Text = 'Lock';
            app.LockSwitch6 = uiswitch(app.UIFigure, 'slider');
            app.LockSwitch6.Items = {'0', '1'};
            app.LockSwitch6.ValueChangedFcn = createCallbackFcn(app, @LockSwitch6ValueChanged, true);
            app.LockSwitch6.Value = '0';
            app.Cover = uiimage(app.UIFigure);
            app.Cover.ImageSource = 'cover.jpg';
            app.CoverRollAgain_1 = uiimage(app.UIFigure);
            app.CoverRollAgain_1.ImageSource = 'cover_rollagain_1.jpg';
            app.CoverRollAgain_2 = uiimage(app.UIFigure);
            app.CoverRollAgain_2.ImageSource = 'cover_rollagain_1.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_Dice_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end