classdef Final_Dice_App_updated < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image11                 matlab.ui.control.Image
        Image12                 matlab.ui.control.Image
        Player2Name             matlab.ui.control.Label
        Player1Name             matlab.ui.control.Label
        RulesAndScoringButton   matlab.ui.control.Button
        ReturnToMainMenuButton  matlab.ui.control.Button
        FirstRollButton_1       matlab.ui.control.Button
        FirstRollButton_2       matlab.ui.control.Button
        RollAgainButton_1       matlab.ui.control.Button
        RollAgainButton_2       matlab.ui.control.Button
        ResetButton             matlab.ui.control.Button
        NewGameButton           matlab.ui.control.Button
        Image1                  matlab.ui.control.Image
        Image2                  matlab.ui.control.Image
        Image3                  matlab.ui.control.Image
        Image4                  matlab.ui.control.Image
        Image5                  matlab.ui.control.Image
        Image6                  matlab.ui.control.Image
        LockSwitchLabel         matlab.ui.control.Label
        LockSwitch1             matlab.ui.control.Switch
        LockSwitchLabel_2       matlab.ui.control.Label
        LockSwitch2             matlab.ui.control.Switch
        LockSwitchLabel_3       matlab.ui.control.Label
        LockSwitch3             matlab.ui.control.Switch
        LockSwitchLabel_4       matlab.ui.control.Label
        LockSwitch4             matlab.ui.control.Switch
        LockSwitchLabel_5       matlab.ui.control.Label
        LockSwitch5             matlab.ui.control.Switch
        LockSwitchLabel_6       matlab.ui.control.Label
        LockSwitch6             matlab.ui.control.Switch
        UITable                 matlab.ui.control.Table
        ScoreEditFieldLabel     matlab.ui.control.Label
        ScoreEditField          matlab.ui.control.NumericEditField
        ScoreEditField_2Label   matlab.ui.control.Label
        ScoreEditField_2        matlab.ui.control.NumericEditField
        Cover                   matlab.ui.control.Image
        CoverRollAgain_1        matlab.ui.control.Image
        CoverRollAgain_2        matlab.ui.control.Image
        TextArea                matlab.ui.control.TextArea
        Image_win1              matlab.ui.control.Image
        Image_win2              matlab.ui.control.Image
        RulesAndScoringPanel    matlab.ui.container.Panel
        DaRulesLabel            matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        RulesText               matlab.ui.control.TextArea
        ScoringGuidelinesLabel  matlab.ui.control.Label
        Label_2                 matlab.ui.control.Label
        ScoringGuidelinesText   matlab.ui.control.TextArea
        ContinueButton_2        matlab.ui.control.Button
        WelcomePanel            matlab.ui.container.Panel
        DiceGameLabel           matlab.ui.control.Label
        Player1EditFieldLabel   matlab.ui.control.Label
        Player1EditField        matlab.ui.control.EditField
        Player2EditFieldLabel   matlab.ui.control.Label
        Player2EditField        matlab.ui.control.EditField
        ContinueButton          matlab.ui.control.Button
        Image7                  matlab.ui.control.Image
        Image8                  matlab.ui.control.Image
        Image9                  matlab.ui.control.Image
        Image10                 matlab.ui.control.Image
        Player1Label            matlab.ui.control.Label
        Player2Label            matlab.ui.control.Label
        PickyourcharacterLabel  matlab.ui.control.Label
    end


    properties (Access = public)
        channelID = 1316263
        writeAPIkey = 'W4I0IHLQTWF92FWL'
        readAPIkey = '22NPY7JX5JZX59BU'
        
        die1 = 0
        die2 = 0
        die3 = 0
        die4 = 0
        die5 = 0
        die6 = 0
        
        die1_2 = 0
        die2_2 = 0
        die3_2 = 0
        die4_2 = 0
        die5_2 = 0
        die6_2 = 0
        
        totalScore = 0
        totalScore_2 = 0
        rollType = []
        rollType_2 = []


        roll_count = 0
        
        reset_count = 0
        
        
    end
    
    methods (Static)
        function rollType = CalculateRoll(roll)
            
            numberOfOnes = length(find(roll == 1));
            numberOfTwos = length(find(roll == 2));
            numberOfThrees = length(find(roll == 3));
            numberOfFours = length(find(roll == 4));
            numberOfFives = length(find(roll == 5));
            numberOfSixes = length(find(roll == 6));
    
            Ones = 0;
            Fives = 0;
            threeOnes = 0;
            threeTwos =  0;
            threeThrees = 0;
            threeFours = 0;
            threeFives = 0;
            threeSixes = 0;
            straight = 0;
            threePairsRolled = 0;
            fourOfAKindOnePairRolled = 0;
    
            temp1 = numberOfOnes;
            temp5 = numberOfFives;
    
            for temp1 = 1:numberOfOnes
                Ones = Ones + 1;
            end
    
            for temp5 = 1:numberOfFives
                Fives = Fives + 1;
            end
    
            if numberOfOnes == 3
                threeOnes = threeOnes + 1;
            end
    
            if numberOfTwos == 3
                threeTwos = threeTwos + 1;
            end
            if numberOfThrees == 3
            threeThrees = threeThrees + 1;
            end
            if numberOfFours == 3
            threeFours = threeFours + 1;
            end
            if numberOfFives == 3
            threeFives = threeFives + 1;
            end
            if numberOfSixes == 3
            threeSixes = threeSixes + 1;
            end
            if numberOfOnes == 1 && numberOfTwos == 1 && numberOfThrees == 1 &&...
            numberOfFours == 1 && numberOfFives == 1 && numberOfSixes == 1
            straight = straight + 1;
            end
            temp = [numberOfOnes, numberOfTwos, numberOfThrees, numberOfFours, numberOfFives, numberOfSixes];
            threePairs = length(find(temp == 2)) == 3;
            fourOfAKindOnePair = length(find(temp == 2)) == 1 && ...
            length(find(temp == 4)) == 1;
            if threePairs
            threePairsRolled = threePairsRolled + 1;
            end
            if fourOfAKindOnePair
            fourOfAKindOnePairRolled = fourOfAKindOnePairRolled + 1;
            end
            rollType = [Ones; Fives; threeOnes; threeTwos; threeThrees;...
            threeFours; threeFives; threeSixes; straight; ...
            threePairsRolled; fourOfAKindOnePairRolled];
            end
    end


    methods (Static)
        
        function totalScore = ComputeScore(rollType)
            scoreOnes = rollType(1)*100
            scoreFives = rollType(2)*50
            scorethreeOnes = rollType(3)*1000
            scorethreeTwos = rollType(4)*200
            scorethreeThrees = rollType(5)*300
            scorethreeFours = rollType(6)*400
            scorethreeFives = rollType(7)*500
            scorethreeSixes = rollType(8)*600
            scoreStraight = rollType(9)*3000
            scoreThreePairsRolled = rollType(10)*1500
            scoreFourOfAKindOnePairRolled = rollType(11)*1500
            totalScore = sum([scoreOnes,scoreFives,scorethreeOnes,scorethreeTwos,scorethreeThrees,scorethreeFours,scorethreeFives,scorethreeSixes,scoreStraight,scoreThreePairsRolled,scoreFourOfAKindOnePairRolled])


        end
    end
    
    methods (Static)
        
        function player = diceroll();
            [y,Fs]=audioread('[ONTIVA.COM]-Dice - SOUND EFFECT --HQ.wav');
                    player = audioplayer(y,Fs);
                    play(player);
                    pause(2);
            
        end
        function player = start();
            [y,Fs]=audioread('Start.wav');
                    player = audioplayer(y,Fs);
                    play(player);
                    pause(3);
        end
        function player = won();
            [y,Fs]=audioread('Won.wav');
                    player = audioplayer(y,Fs);
                    play(player);
                    pause(2);
        end
        function player = click()
            [y,Fs]=audioread('click.wav');
                    player = audioplayer(y,Fs);
                    play(player);
                    pause(2);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.RowName = ["Ones";"Fives";"Three Ones";"Three Twos";"Three Threes";
                "Three Fours";"Three Fives";"Three Sixes";"Three Pairs"; "Straight";"Four of a Kind and a Pair"]
            app.UITable.Data(:,2) = zeros(1,11)
            app.UITable.Data(:,1) = zeros(1,11)
            app.Image1.ImageSource = 'blank.jpg'
            app.Image2.ImageSource = 'blank.jpg'
            app.Image3.ImageSource = 'blank.jpg'
            app.Image4.ImageSource = 'blank.jpg'
           app.Image5.ImageSource = 'blank.jpg'
           app.Image6.ImageSource = 'blank.jpg'
           app.ScoreEditField.Value = 0
           app.ScoreEditField_2.Value = 0
           app.FirstRollButton_2.Visible = 'off'
           
           thingSpeakWrite(app.channelID, 'Fields', 1, 'Values', 0, 'WriteKey', app.writeAPIkey)
           pause(15)
           thingSpeakWrite(app.channelID, 'Fields', 2, 'Values', 0, 'WriteKey', app.writeAPIkey)


        end

        % Button pushed function: FirstRollButton_1
        function FirstRollButton_1Pushed(app, event)
                    app.diceroll()
                    app.roll_count = app.roll_count + 1
                    
                    value1 = app.LockSwitch1.Value
                    if value1 == '0'
                        app.die1 = randi(6)
                
                        if app.die1 == 1
                            app.Image1.ImageSource = 'dice1.jpg';
                        elseif app.die1 == 2
                            app.Image1.ImageSource = 'dice2.jpg';
                        elseif app.die1 == 3
                            app.Image1.ImageSource = 'dice3.jpg';
                        elseif app.die1 == 4
                            app.Image1.ImageSource = 'dice4.jpg';
                        elseif app.die1 == 5
                            app.Image1.ImageSource = 'dice5.jpg';
                        elseif app.die1 == 6
                            app.Image1.ImageSource = 'dice6.jpg' 
                        end
                    end


                    value2 = app.LockSwitch2.Value
                    if value2 == '0'
                        app.die2 = randi(6)
                   
        
                        if app.die2 == 1
                            app.Image2.ImageSource = 'dice1.jpg';
                        elseif app.die2 == 2
                            app.Image2.ImageSource = 'dice2.jpg';
                        elseif app.die2 == 3
                            app.Image2.ImageSource = 'dice3.jpg';
                         elseif app.die2 == 4
                            app.Image2.ImageSource = 'dice4.jpg';
                         elseif app.die2 == 5
                            app.Image2.ImageSource = 'dice5.jpg';
                         elseif app.die2 == 6
                            app.Image2.ImageSource = 'dice6.jpg';
                        end
                    end
                        
                        
                    value3 = app.LockSwitch3.Value
                    if value3 == '0'
                        app.die3 = randi(6)
                   
        
                        if app.die3 == 1
                            app.Image3.ImageSource = 'dice1.jpg';
                        elseif app.die3 == 2
                            app.Image3.ImageSource = 'dice2.jpg';
                        elseif app.die3 == 3
                            app.Image3.ImageSource = 'dice3.jpg';
                        elseif app.die3 == 4
                            app.Image3.ImageSource = 'dice4.jpg';
                        elseif app.die3 == 5
                            app.Image3.ImageSource = 'dice5.jpg';
                        elseif app.die3 == 6
                            app.Image3.ImageSource = 'dice6.jpg';
                        end
                    end

                    
                    value4 = app.LockSwitch4.Value
                    if value4 == '0'
                        app.die4 = randi(6)
                   
        
                        if app.die4 == 1
                            app.Image4.ImageSource = 'dice1.jpg';
                        elseif app.die4 == 2
                            app.Image4.ImageSource = 'dice2.jpg';
                        elseif app.die4 == 3
                            app.Image4.ImageSource = 'dice3.jpg';
                        elseif app.die4 == 4
                            app.Image4.ImageSource = 'dice4.jpg';
                        elseif app.die4 == 5
                            app.Image4.ImageSource = 'dice5.jpg';
                        elseif app.die4 == 6
                            app.Image4.ImageSource = 'dice6.jpg';
                        end
                    end


                    value5 = app.LockSwitch5.Value
                    if value5 == '0'
                        app.die5 = randi(6)
                   
        
                        if app.die5 == 1
                            app.Image5.ImageSource = 'dice1.jpg';
                        elseif app.die5 == 2
                            app.Image5.ImageSource = 'dice2.jpg';
                        elseif app.die5 == 3
                            app.Image5.ImageSource = 'dice3.jpg';
                        elseif app.die5 == 4
                            app.Image5.ImageSource = 'dice4.jpg';
                        elseif app.die5 == 5
                            app.Image5.ImageSource = 'dice5.jpg';
                        elseif app.die5 == 6
                            app.Image5.ImageSource = 'dice6.jpg';
                        end
                    end


                    value6 = app.LockSwitch6.Value
                    if value6 == '0'
                        app.die6 = randi(6)
                   
        
                        if app.die6 == 1
                            app.Image6.ImageSource = 'dice1.jpg';
                        elseif app.die6 == 2
                            app.Image6.ImageSource = 'dice2.jpg';
                        elseif app.die6 == 3
                            app.Image6.ImageSource = 'dice3.jpg';
                        elseif app.die6 == 4
                            app.Image6.ImageSource = 'dice4.jpg';
                        elseif app.die6 == 5
                            app.Image6.ImageSource = 'dice5.jpg';
                        elseif app.die6 == 6
                            app.Image6.ImageSource = 'dice6.jpg';
                        end
                    end


                    app.Cover.Visible = 'off'
                    
                    app.FirstRollButton_1.Visible = 'off'
                    app.FirstRollButton_2.Visible = 'off'
                    
                    app.CoverRollAgain_1.Visible = 'off'
                    
                    
                    roll=[app.die1, app.die2, app.die3, app.die4, app.die5, app.die6];                    
                    
                    app.rollType = app.CalculateRoll(roll);
                    app.UITable.Data(:,1) = app.rollType
                    app.totalScore =  app.ComputeScore(app.rollType) + app.totalScore
                    app.ScoreEditField.Value = app.totalScore
                    
                   thingSpeakWrite(app.channelID, 'Fields', 1, 'Values', app.ScoreEditField.Value, 'WriteKey', app.writeAPIkey)


        end

        % Button pushed function: FirstRollButton_2
        function FirstRollButton_2Pushed(app, event)
                    app.diceroll()
                    app.roll_count = app.roll_count + 1
                    
                    value1 = app.LockSwitch1.Value
                    if value1 == '0'
                        app.die1_2 = randi(6)
                
                        if app.die1_2 == 1
                            app.Image1.ImageSource = 'dice1.jpg';
                        elseif app.die1_2 == 2
                            app.Image1.ImageSource = 'dice2.jpg';
                        elseif app.die1_2 == 3
                            app.Image1.ImageSource = 'dice3.jpg';
                        elseif app.die1_2 == 4
                            app.Image1.ImageSource = 'dice4.jpg';
                        elseif app.die1_2 == 5
                            app.Image1.ImageSource = 'dice5.jpg';
                        elseif app.die1_2 == 6
                            app.Image1.ImageSource = 'dice6.jpg' 
                        end
                    end


                    value2 = app.LockSwitch2.Value
                    if value2 == '0'
                        app.die2_2 = randi(6)
                   
        
                        if app.die2_2 == 1
                            app.Image2.ImageSource = 'dice1.jpg';
                        elseif app.die2_2 == 2
                            app.Image2.ImageSource = 'dice2.jpg';
                        elseif app.die2_2 == 3
                            app.Image2.ImageSource = 'dice3.jpg';
                         elseif app.die2_2 == 4
                            app.Image2.ImageSource = 'dice4.jpg';
                         elseif app.die2_2 == 5
                            app.Image2.ImageSource = 'dice5.jpg';
                         elseif app.die2_2 == 6
                            app.Image2.ImageSource = 'dice6.jpg';
                        end
                    end
                        
                        
                    value3 = app.LockSwitch3.Value
                    if value3 == '0'
                        app.die3_2 = randi(6)
                   
        
                        if app.die3_2 == 1
                            app.Image3.ImageSource = 'dice1.jpg';
                        elseif app.die3_2 == 2
                            app.Image3.ImageSource = 'dice2.jpg';
                        elseif app.die3_2 == 3
                            app.Image3.ImageSource = 'dice3.jpg';
                        elseif app.die3_2 == 4
                            app.Image3.ImageSource = 'dice4.jpg';
                        elseif app.die3_2 == 5
                            app.Image3.ImageSource = 'dice5.jpg';
                        elseif app.die3_2 == 6
                            app.Image3.ImageSource = 'dice6.jpg';
                        end
                    end

                    
                    value4 = app.LockSwitch4.Value
                    if value4 == '0'
                        app.die4_2 = randi(6)
                   
        
                        if app.die4_2 == 1
                            app.Image4.ImageSource = 'dice1.jpg';
                        elseif app.die4_2 == 2
                            app.Image4.ImageSource = 'dice2.jpg';
                        elseif app.die4_2 == 3
                            app.Image4.ImageSource = 'dice3.jpg';
                        elseif app.die4_2 == 4
                            app.Image4.ImageSource = 'dice4.jpg';
                        elseif app.die4_2 == 5
                            app.Image4.ImageSource = 'dice5.jpg';
                        elseif app.die4_2 == 6
                            app.Image4.ImageSource = 'dice6.jpg';
                        end
                    end


                    value5 = app.LockSwitch5.Value
                    if value5 == '0'
                        app.die5_2 = randi(6)
                   
        
                        if app.die5_2 == 1
                            app.Image5.ImageSource = 'dice1.jpg';
                        elseif app.die5_2 == 2
                            app.Image5.ImageSource = 'dice2.jpg';
                        elseif app.die5_2 == 3
                            app.Image5.ImageSource = 'dice3.jpg';
                        elseif app.die5_2 == 4
                            app.Image5.ImageSource = 'dice4.jpg';
                        elseif app.die5_2 == 5
                            app.Image5.ImageSource = 'dice5.jpg';
                        elseif app.die5_2 == 6
                            app.Image5.ImageSource = 'dice6.jpg';
                        end
                    end


                    value6 = app.LockSwitch6.Value
                    if value6 == '0'
                        app.die6_2 = randi(6)
                   
        
                        if app.die6_2 == 1
                            app.Image6.ImageSource = 'dice1.jpg';
                        elseif app.die6_2 == 2
                            app.Image6.ImageSource = 'dice2.jpg';
                        elseif app.die6_2 == 3
                            app.Image6.ImageSource = 'dice3.jpg';
                        elseif app.die6_2 == 4
                            app.Image6.ImageSource = 'dice4.jpg';
                        elseif app.die6_2 == 5
                            app.Image6.ImageSource = 'dice5.jpg';
                        elseif app.die6_2 == 6
                            app.Image6.ImageSource = 'dice6.jpg';
                        end
                    end

                    app.Cover.Visible = 'off'
                    
                    app.FirstRollButton_2.Visible = 'off'
                    app.FirstRollButton_1.Visible = 'off'
                    
                    app.CoverRollAgain_2.Visible = 'off'
                    
                    roll=[app.die1_2, app.die2_2, app.die3_2, app.die4_2, app.die5_2, app.die6_2]                    
                    app.rollType_2 = app.CalculateRoll(roll);
                    app.UITable.Data(:,2) = app.rollType_2
                    app.totalScore_2 =  app.ComputeScore(app.rollType_2) + app.totalScore_2;
                    app.ScoreEditField_2.Value = app.totalScore_2

                   
                    thingSpeakWrite(app.channelID, 'Fields', 2, 'Values', app.ScoreEditField_2.Value, 'WriteKey', app.writeAPIkey)


        end

        % Value changed function: LockSwitch1
        function LockSwitch1ValueChanged(app, event)
            value1 = app.LockSwitch1.Value
            
           
            if value1 == '1'
                app.die1 = 0
                x = app.die1
                if strcmp(app.Image1.ImageSource, 'dice1.jpg')
                    app.Image1.ImageSource = 'dice1.jpg'
                    app.die1 = 1
                elseif strcmp(app.Image1.ImageSource, 'dice2.jpg')
                    app.Image1.ImageSource = 'dice2.jpg'
                    app.die1 = 2
                elseif strcmp(app.Image1.ImageSource, 'dice3.jpg')
                    app.Image1.ImageSource = 'dice3.jpg'
                    app.die1 = 3
                elseif strcmp(app.Image1.ImageSource, 'dice4.jpg')
                    app.Image1.ImageSource = 'dice4.jpg'
                    app.die1 = 4
                elseif strcmp(app.Image1.ImageSource, 'dice5.jpg')
                    app.Image1.ImageSource = 'dice5.jpg'
                    app.die1 = 5
                elseif strcmp(app.Image1.ImageSource, 'dice6.jpg')
                    app.Image1.ImageSource = 'dice6.jpg'
                    app.die1 = 6
                end


            elseif value1 == '0'
                app.die1 = randi(6)
                x = app.die1
            end
            
            
        end

        % Value changed function: LockSwitch2
        function LockSwitch2ValueChanged(app, event)
            value2 = app.LockSwitch2.Value
            
           
            if value2 == '1'
                app.die2 = 0
                x = app.die2
                if strcmp(app.Image2.ImageSource, 'dice1.jpg')
                    app.Image2.ImageSource = 'dice1.jpg'
                    app.die2 = 1
                elseif strcmp(app.Image2.ImageSource, 'dice2.jpg')
                    app.Image2.ImageSource = 'dice2.jpg'
                    app.die2 = 2
                elseif strcmp(app.Image2.ImageSource, 'dice3.jpg')
                    app.Image2.ImageSource = 'dice3.jpg'
                    app.die2 = 3
                elseif strcmp(app.Image2.ImageSource, 'dice4.jpg')
                    app.Image2.ImageSource = 'dice4.jpg'
                    app.die2 = 4
                elseif strcmp(app.Image2.ImageSource, 'dice5.jpg')
                    app.Image2.ImageSource = 'dice5.jpg'
                    app.die2 = 5
                elseif strcmp(app.Image2.ImageSource, 'dice6.jpg')
                    app.Image2.ImageSource = 'dice6.jpg'
                    app.die2 = 6
                end


            elseif value2 == '0'
                app.die2 = randi(6)
                x = app.die2
            end
            
            
        end

        % Value changed function: LockSwitch3
        function LockSwitch3ValueChanged(app, event)
            value3 = app.LockSwitch3.Value
            
           
            if value3 == '1'
                app.die3 = 0
                x = app.die3
                if strcmp(app.Image3.ImageSource, 'dice1.jpg')
                    app.Image3.ImageSource = 'dice1.jpg'
                    app.die3 = 1
                elseif strcmp(app.Image3.ImageSource, 'dice2.jpg')
                    app.Image3.ImageSource = 'dice2.jpg'
                    app.die3 = 2
                elseif strcmp(app.Image3.ImageSource, 'dice3.jpg')
                    app.Image3.ImageSource = 'dice3.jpg'
                    app.die3 = 3
                elseif strcmp(app.Image3.ImageSource, 'dice4.jpg')
                    app.Image3.ImageSource = 'dice4.jpg'
                    app.die3 = 4
                elseif strcmp(app.Image3.ImageSource, 'dice5.jpg')
                    app.Image3.ImageSource = 'dice5.jpg'
                    app.die3 = 5
                elseif strcmp(app.Image3.ImageSource, 'dice6.jpg')
                    app.Image3.ImageSource = 'dice6.jpg'
                    app.die3 = 6
                end


            elseif value3 == '0'
                app.die3 = randi(6)
                x = app.die3
            end
            
            
        end

        % Value changed function: LockSwitch4
        function LockSwitch4ValueChanged(app, event)
            value4 = app.LockSwitch4.Value;

            
            if value4 == '1'
                app.die4 = 0
                x = app.die4
                if strcmp(app.Image4.ImageSource, 'dice1.jpg')
                    app.Image4.ImageSource = 'dice1.jpg'
                    app.die4 = 1
                elseif strcmp(app.Image4.ImageSource, 'dice2.jpg')
                    app.Image4.ImageSource = 'dice2.jpg'
                    app.die4 = 2
                elseif strcmp(app.Image4.ImageSource, 'dice3.jpg')
                    app.Image4.ImageSource = 'dice3.jpg'
                    app.die4 = 3
                elseif strcmp(app.Image4.ImageSource, 'dice4.jpg')
                    app.Image4.ImageSource = 'dice4.jpg'
                    app.die4 = 4
                elseif strcmp(app.Image4.ImageSource, 'dice5.jpg')
                    app.Image4.ImageSource = 'dice5.jpg'
                    app.die4 = 5
                elseif strcmp(app.Image4.ImageSource, 'dice6.jpg')
                    app.Image4.ImageSource = 'dice6.jpg'
                    app.die4 = 6
                    
                end


            elseif value4 == '0'
                app.die4 = randi(6)
                x = app.die4
            end
            
             
        end

        % Value changed function: LockSwitch5
        function LockSwitch5ValueChanged(app, event)
            value5 = app.LockSwitch5.Value;
            
            
            if value5 == '1'
                app.die5 = 0
                x = app.die5
                if strcmp(app.Image5.ImageSource, 'dice1.jpg')
                    app.Image5.ImageSource = 'dice1.jpg'
                    app.die5 = 1
                elseif strcmp(app.Image5.ImageSource, 'dice2.jpg')
                    app.Image5.ImageSource = 'dice2.jpg'
                    app.die5 = 2
                elseif strcmp(app.Image5.ImageSource, 'dice3.jpg')
                    app.Image5.ImageSource = 'dice3.jpg'
                    app.die5 = 3
                elseif strcmp(app.Image5.ImageSource, 'dice4.jpg')
                    app.Image5.ImageSource = 'dice4.jpg'
                    app.die5 = 4
                elseif strcmp(app.Image5.ImageSource, 'dice5.jpg')
                    app.Image5.ImageSource = 'dice5.jpg'
                    app.die5 = 5
                elseif strcmp(app.Image5.ImageSource, 'dice6.jpg')
                    app.Image5.ImageSource = 'dice6.jpg'
                    app.die5 = 6
                end


            elseif value5 == '0'
                app.die5 = randi(6)
                x = app.die5
            end


        end

        % Value changed function: LockSwitch6
        function LockSwitch6ValueChanged(app, event)
            value6 = app.LockSwitch6.Value;


            if value6 == '1'
                app.die6 = 0
                x = app.die6
                if strcmp(app.Image6.ImageSource, 'dice1.jpg')
                    app.Image6.ImageSource = 'dice1.jpg'
                    app.die6 = 1
                elseif strcmp(app.Image6.ImageSource, 'dice2.jpg')
                    app.Image6.ImageSource = 'dice2.jpg'
                    app.die6 = 2
                elseif strcmp(app.Image6.ImageSource, 'dice3.jpg')
                    app.Image6.ImageSource = 'dice3.jpg'
                    app.die6 = 3
                elseif strcmp(app.Image6.ImageSource, 'dice4.jpg')
                    app.Image6.ImageSource = 'dice4.jpg'
                    app.die6 = 4
                elseif strcmp(app.Image6.ImageSource, 'dice5.jpg')
                    app.Image6.ImageSource = 'dice5.jpg'
                    app.die6 = 5
                elseif strcmp(app.Image6.ImageSource, 'dice6.jpg')
                    app.Image6.ImageSource = 'dice6.jpg'
                    app.die6 = 6
                end


            elseif value6 == '0'
                app.die6 = randi(6)
                x = app.die6
            end
            
                             
        end

        % Button pushed function: RollAgainButton_1
        function RollAgainButton_1Pushed(app, event)
                    app.diceroll()
                    app.roll_count = app.roll_count + 1
                    
                    if app.roll_count >= 5
                        app.RollAgainButton_1.Visible = 'off'
                                            

                    end
                    
                    value1 = app.LockSwitch1.Value
                    if value1 == '0'
                        app.die1 = randi(6)
                
                        if app.die1 == 1
                            app.Image1.ImageSource = 'dice1.jpg';
                        elseif app.die1 == 2
                            app.Image1.ImageSource = 'dice2.jpg';
                        elseif app.die1 == 3
                            app.Image1.ImageSource = 'dice3.jpg';
                        elseif app.die1 == 4
                            app.Image1.ImageSource = 'dice4.jpg';
                        elseif app.die1 == 5
                            app.Image1.ImageSource = 'dice5.jpg';
                        elseif app.die1 == 6
                            app.Image1.ImageSource = 'dice6.jpg' 
                        end
                    end


                    value2 = app.LockSwitch2.Value
                    if value2 == '0'
                        app.die2 = randi(6)
                   
        
                        if app.die2 == 1
                            app.Image2.ImageSource = 'dice1.jpg';
                        elseif app.die2 == 2
                            app.Image2.ImageSource = 'dice2.jpg';
                        elseif app.die2 == 3
                            app.Image2.ImageSource = 'dice3.jpg';
                         elseif app.die2 == 4
                            app.Image2.ImageSource = 'dice4.jpg';
                         elseif app.die2 == 5
                            app.Image2.ImageSource = 'dice5.jpg';
                         elseif app.die2 == 6
                            app.Image2.ImageSource = 'dice6.jpg';
                        end
                    end
                        
                        
                    value3 = app.LockSwitch3.Value
                    if value3 == '0'
                        app.die3 = randi(6)
                   
        
                        if app.die3 == 1
                            app.Image3.ImageSource = 'dice1.jpg';
                        elseif app.die3 == 2
                            app.Image3.ImageSource = 'dice2.jpg';
                        elseif app.die3 == 3
                            app.Image3.ImageSource = 'dice3.jpg';
                        elseif app.die3 == 4
                            app.Image3.ImageSource = 'dice4.jpg';
                        elseif app.die3 == 5
                            app.Image3.ImageSource = 'dice5.jpg';
                        elseif app.die3 == 6
                            app.Image3.ImageSource = 'dice6.jpg';
                        end
                    end

                    
                    value4 = app.LockSwitch4.Value
                    if value4 == '0'
                        app.die4 = randi(6)
                   
        
                        if app.die4 == 1
                            app.Image4.ImageSource = 'dice1.jpg';
                        elseif app.die4 == 2
                            app.Image4.ImageSource = 'dice2.jpg';
                        elseif app.die4 == 3
                            app.Image4.ImageSource = 'dice3.jpg';
                        elseif app.die4 == 4
                            app.Image4.ImageSource = 'dice4.jpg';
                        elseif app.die4 == 5
                            app.Image4.ImageSource = 'dice5.jpg';
                        elseif app.die4 == 6
                            app.Image4.ImageSource = 'dice6.jpg';
                        end
                    end


                    value5 = app.LockSwitch5.Value
                    if value5 == '0'
                        app.die5 = randi(6)
                   
        
                        if app.die5 == 1
                            app.Image5.ImageSource = 'dice1.jpg';
                        elseif app.die5 == 2
                            app.Image5.ImageSource = 'dice2.jpg';
                        elseif app.die5 == 3
                            app.Image5.ImageSource = 'dice3.jpg';
                        elseif app.die5 == 4
                            app.Image5.ImageSource = 'dice4.jpg';
                        elseif app.die5 == 5
                            app.Image5.ImageSource = 'dice5.jpg';
                        elseif app.die5 == 6
                            app.Image5.ImageSource = 'dice6.jpg';
                        end
                    end


                    value6 = app.LockSwitch6.Value
                    if value6 == '0'
                        app.die6 = randi(6)
                   
        
                        if app.die6 == 1
                            app.Image6.ImageSource = 'dice1.jpg';
                        elseif app.die6 == 2
                            app.Image6.ImageSource = 'dice2.jpg';
                        elseif app.die6 == 3
                            app.Image6.ImageSource = 'dice3.jpg';
                        elseif app.die6 == 4
                            app.Image6.ImageSource = 'dice4.jpg';
                        elseif app.die6 == 5
                            app.Image6.ImageSource = 'dice5.jpg';
                        elseif app.die6 == 6
                            app.Image6.ImageSource = 'dice6.jpg';
                        end
                    end


                    roll=[app.die1, app.die2, app.die3, app.die4, app.die5, app.die6];                    
                    
                    app.rollType = app.CalculateRoll(roll);
                    app.UITable.Data(:,1) = app.rollType
                    app.totalScore =  app.ComputeScore(app.rollType) + app.totalScore
                    app.ScoreEditField.Value = app.totalScore
                    
                    
                    thingSpeakWrite(app.channelID, 'Fields', 1, 'Values', app.ScoreEditField.Value, 'WriteKey', app.writeAPIkey)


        end

        % Button pushed function: RollAgainButton_2
        function RollAgainButton_2Pushed(app, event)
                    app.diceroll()
                    app.roll_count = app.roll_count + 1
                    
                    if app.roll_count >= 5
                        app.RollAgainButton_2.Visible = 'off'
                    end


                    value1 = app.LockSwitch1.Value
                    if value1 == '0'
                        app.die1_2 = randi(6)
                
                        if app.die1_2 == 1
                            app.Image1.ImageSource = 'dice1.jpg';
                        elseif app.die1_2 == 2
                            app.Image1.ImageSource = 'dice2.jpg';
                        elseif app.die1_2 == 3
                            app.Image1.ImageSource = 'dice3.jpg';
                        elseif app.die1_2 == 4
                            app.Image1.ImageSource = 'dice4.jpg';
                        elseif app.die1_2 == 5
                            app.Image1.ImageSource = 'dice5.jpg';
                        elseif app.die1_2 == 6
                            app.Image1.ImageSource = 'dice6.jpg' 
                        end
                    end


                    value2 = app.LockSwitch2.Value
                    if value2 == '0'
                        app.die2_2 = randi(6)
                   
        
                        if app.die2_2 == 1
                            app.Image2.ImageSource = 'dice1.jpg';
                        elseif app.die2_2 == 2
                            app.Image2.ImageSource = 'dice2.jpg';
                        elseif app.die2_2 == 3
                            app.Image2.ImageSource = 'dice3.jpg';
                         elseif app.die2_2 == 4
                            app.Image2.ImageSource = 'dice4.jpg';
                         elseif app.die2_2 == 5
                            app.Image2.ImageSource = 'dice5.jpg';
                         elseif app.die2_2 == 6
                            app.Image2.ImageSource = 'dice6.jpg';
                        end
                    end
                        
                        
                    value3 = app.LockSwitch3.Value
                    if value3 == '0'
                        app.die3_2 = randi(6)
                   
        
                        if app.die3_2 == 1
                            app.Image3.ImageSource = 'dice1.jpg';
                        elseif app.die3_2 == 2
                            app.Image3.ImageSource = 'dice2.jpg';
                        elseif app.die3_2 == 3
                            app.Image3.ImageSource = 'dice3.jpg';
                        elseif app.die3_2 == 4
                            app.Image3.ImageSource = 'dice4.jpg';
                        elseif app.die3_2 == 5
                            app.Image3.ImageSource = 'dice5.jpg';
                        elseif app.die3_2 == 6
                            app.Image3.ImageSource = 'dice6.jpg';
                        end
                    end

                    
                    value4 = app.LockSwitch4.Value
                    if value4 == '0'
                        app.die4_2 = randi(6)
                   
        
                        if app.die4_2 == 1
                            app.Image4.ImageSource = 'dice1.jpg';
                        elseif app.die4_2 == 2
                            app.Image4.ImageSource = 'dice2.jpg';
                        elseif app.die4_2 == 3
                            app.Image4.ImageSource = 'dice3.jpg';
                        elseif app.die4_2 == 4
                            app.Image4.ImageSource = 'dice4.jpg';
                        elseif app.die4_2 == 5
                            app.Image4.ImageSource = 'dice5.jpg';
                        elseif app.die4_2 == 6
                            app.Image4.ImageSource = 'dice6.jpg';
                        end
                    end


                    value5 = app.LockSwitch5.Value
                    if value5 == '0'
                        app.die5_2 = randi(6)
                   
        
                        if app.die5_2 == 1
                            app.Image5.ImageSource = 'dice1.jpg';
                        elseif app.die5_2 == 2
                            app.Image5.ImageSource = 'dice2.jpg';
                        elseif app.die5_2 == 3
                            app.Image5.ImageSource = 'dice3.jpg';
                        elseif app.die5_2 == 4
                            app.Image5.ImageSource = 'dice4.jpg';
                        elseif app.die5_2 == 5
                            app.Image5.ImageSource = 'dice5.jpg';
                        elseif app.die5_2 == 6
                            app.Image5.ImageSource = 'dice6.jpg';
                        end
                    end


                    value6 = app.LockSwitch6.Value
                    if value6 == '0'
                        app.die6_2 = randi(6)
                   
        
                        if app.die6_2 == 1
                            app.Image6.ImageSource = 'dice1.jpg';
                        elseif app.die6_2 == 2
                            app.Image6.ImageSource = 'dice2.jpg';
                        elseif app.die6_2 == 3
                            app.Image6.ImageSource = 'dice3.jpg';
                        elseif app.die6_2 == 4
                            app.Image6.ImageSource = 'dice4.jpg';
                        elseif app.die6_2 == 5
                            app.Image6.ImageSource = 'dice5.jpg';
                        elseif app.die6_2 == 6
                            app.Image6.ImageSource = 'dice6.jpg';
                        end
                    end


                    roll=[app.die1_2, app.die2_2, app.die3_2, app.die4_2, app.die5_2, app.die6_2]                    
                    app.rollType_2 = app.CalculateRoll(roll);
                    app.UITable.Data(:,2) = app.rollType_2
                    app.totalScore_2 =  app.ComputeScore(app.rollType_2) + app.totalScore_2;
                    app.ScoreEditField_2.Value = app.totalScore_2

                    thingSpeakWrite(app.channelID, 'Fields', 2, 'Values', app.ScoreEditField_2.Value, 'WriteKey', app.writeAPIkey)         
                 
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Image1.ImageSource = 'blank.jpg';
            app.Image2.ImageSource = 'blank.jpg';
            app.Image3.ImageSource = 'blank.jpg';
            app.Image4.ImageSource = 'blank.jpg';
            app.Image5.ImageSource = 'blank.jpg';
            app.Image6.ImageSource = 'blank.jpg';
            
            app.LockSwitch1.Value = '0';
            app.LockSwitch2.Value = '0';
            app.LockSwitch3.Value = '0';
            app.LockSwitch4.Value = '0';
            app.LockSwitch5.Value = '0';
            app.LockSwitch6.Value = '0';
            
            app.Cover.Visible = 'on';
            
            app.FirstRollButton_1.Visible = 'on';
            app.FirstRollButton_2.Visible = 'on';
            app.CoverRollAgain_1.Visible = 'on';
            app.CoverRollAgain_2.Visible = 'on'
            
            app.roll_count = 0
            
           app.RollAgainButton_1.Visible = 'on'
           app.RollAgainButton_2.Visible = 'on'
           
           app.reset_count = app.reset_count + 1
           
           if rem(app.reset_count, 2) == 0
               app.FirstRollButton_2.Visible = 'off'
               app.FirstRollButton_1.Visible = 'on'
               app.ScoreEditField_2.Value = thingSpeakRead(app.channelID, 'Fields', 2, 'ReadKey', app.readAPIkey)
           elseif rem(app.reset_count, 2) == 1
               app.FirstRollButton_1.Visible = 'off'
               app.FirstRollButton_2.Visible = 'on'
               app.ScoreEditField.Value = thingSpeakRead(app.channelID, 'Fields', 1, 'ReadKey', app.readAPIkey)
           end
           
           
           if app.reset_count >= 6
               if app.ScoreEditField.Value > app.ScoreEditField_2.Value
                   app.Image_win1.Visible = 'on'
                   app.Image_win2.Visible = 'off'
                   app.RollAgainButton_2.Visible = 'off'
                   app.RollAgainButton_2.Visible = 'off'
                   app.won()
                   
               else
                   app.Image_win2.Visible = 'on'
                   app.Image_win1.Visible = 'off'
                   app.RollAgainButton_1.Visible = 'off'
                   app.FirstRollButton_1.Visible = 'off'
                   app.won()
               end

           end


        end

        % Button pushed function: NewGameButton
        function NewGameButtonPushed(app, event)
            app.Image1.ImageSource = 'blank.jpg';
            app.Image2.ImageSource = 'blank.jpg';
            app.Image3.ImageSource = 'blank.jpg';
            app.Image4.ImageSource = 'blank.jpg';
            app.Image5.ImageSource = 'blank.jpg';
            app.Image6.ImageSource = 'blank.jpg';
            
            app.LockSwitch1.Value = '0';
            app.LockSwitch2.Value = '0';
            app.LockSwitch3.Value = '0';
            app.LockSwitch4.Value = '0';
            app.LockSwitch5.Value = '0';
            app.LockSwitch6.Value = '0';
            
            app.Cover.Visible = 'on';
            
            app.FirstRollButton_1.Visible = 'off';
            app.FirstRollButton_2.Visible = 'on';
            app.CoverRollAgain_1.Visible = 'on';
            app.CoverRollAgain_2.Visible = 'on'


            app.ScoreEditField.Value = 0
            app.ScoreEditField_2.Value = 0
           
            app.roll_count = 0
            
           app.RollAgainButton_1.Visible = 'on'
           app.RollAgainButton_2.Visible = 'on'
           
           
           app.Image_win1.Visible = 'off'
           app.Image_win2.Visible = 'off'
           
           app.reset_count = 0


           thingSpeakWrite(app.channelID, 'Fields', 1, 'Values', 0, 'WriteKey', app.writeAPIkey)
           pause(15)
           thingSpeakWrite(app.channelID, 'Fields', 2, 'Values', 0, 'WriteKey', app.writeAPIkey)


        end

        % Value changed function: Player1EditField
        function Player1EditFieldValueChanged(app, event)
            player1Name = app.Player1EditField.Value;
            app.Player1Name.Text = player1Name;
            
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            app.WelcomePanel.Visible = "off";
            
        end

        % Button pushed function: ContinueButton_2
        function ContinueButton_2Pushed(app, event)
            
            app.RulesAndScoringPanel.Visible = "off";
            app.start()
        end

        % Button pushed function: RulesAndScoringButton
        function RulesAndScoringButtonPushed(app, event)
            app.RulesAndScoringPanel.Visible = "on";
        end

        % Button pushed function: ReturnToMainMenuButton
        function ReturnToMainMenuButtonPushed(app, event)
            app.WelcomePanel.Visible = "on";
            app.RulesAndScoringPanel.Visible = "on";
        end

        % Value changed function: Player2EditField
        function Player2EditFieldValueChanged(app, event)
            player2Name = app.Player2EditField.Value;
            app.Player2Name.Text = player2Name;
            
        end

        % Image clicked function: Image7
        function Image7Clicked(app, event)
            app.Image11.ImageSource = 'mickey.png';
            app.click()
        end

        % Image clicked function: Image8
        function Image8Clicked(app, event)
            app.Image11.ImageSource = 'minnie.jpeg';
            app.click()
        end

        % Image clicked function: Image9
        function Image9Clicked(app, event)
            app.Image12.ImageSource = 'donald.png'
            app.click()
        end

        % Image clicked function: Image10
        function Image10Clicked(app, event)
            app.Image12.ImageSource = 'daisy.png'
            app.click()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image11 = uiimage(app.UIFigure);
            app.Image12 = uiimage(app.UIFigure);
            app.Player2Name = uilabel(app.UIFigure);
            app.Player2Name.Text = 'Label2';
            app.Player1Name = uilabel(app.UIFigure);
            app.RulesAndScoringButton = uibutton(app.UIFigure, 'push');
            app.RulesAndScoringButton.ButtonPushedFcn = createCallbackFcn(app, @RulesAndScoringButtonPushed, true);
            app.RulesAndScoringButton.Text = 'Rules and Scoring';
            app.ReturnToMainMenuButton = uibutton(app.UIFigure, 'push');
            app.ReturnToMainMenuButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnToMainMenuButtonPushed, true);
            app.ReturnToMainMenuButton.Text = 'Return to Main Menu';
            app.FirstRollButton_1 = uibutton(app.UIFigure, 'push');
            app.FirstRollButton_1.ButtonPushedFcn = createCallbackFcn(app, @FirstRollButton_1Pushed, true);
            app.FirstRollButton_1.Text = 'First Roll';
            app.FirstRollButton_2 = uibutton(app.UIFigure, 'push');
            app.FirstRollButton_2.ButtonPushedFcn = createCallbackFcn(app, @FirstRollButton_2Pushed, true);
            app.FirstRollButton_2.Text = 'First Roll';
            app.RollAgainButton_1 = uibutton(app.UIFigure, 'push');
            app.RollAgainButton_1.ButtonPushedFcn = createCallbackFcn(app, @RollAgainButton_1Pushed, true);
            app.RollAgainButton_1.Text = 'Roll Again';
            app.RollAgainButton_2 = uibutton(app.UIFigure, 'push');
            app.RollAgainButton_2.ButtonPushedFcn = createCallbackFcn(app, @RollAgainButton_2Pushed, true);
            app.RollAgainButton_2.Text = 'Roll Again';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.NewGameButton = uibutton(app.UIFigure, 'push');
            app.NewGameButton.ButtonPushedFcn = createCallbackFcn(app, @NewGameButtonPushed, true);
            app.NewGameButton.Text = 'NewGame';
            app.Image1 = uiimage(app.UIFigure);
            app.Image1.ImageSource = 'blank.jpg';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'blank.jpg';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'blank.jpg';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = 'blank.jpg';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = 'blank.jpg';
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageSource = 'blank.jpg';
            app.LockSwitchLabel = uilabel(app.UIFigure);
            app.LockSwitchLabel.Text = 'Lock';
            app.LockSwitch1 = uiswitch(app.UIFigure, 'slider');
            app.LockSwitch1.Items = {'0', '1'};
            app.LockSwitch1.ValueChangedFcn = createCallbackFcn(app, @LockSwitch1ValueChanged, true);
            app.LockSwitch1.Value = '0';
            app.LockSwitchLabel_2 = uilabel(app.UIFigure);
            app.LockSwitchLabel_2.Text = 'Lock';
            app.LockSwitch2 = uiswitch(app.UIFigure, 'slider');
            app.LockSwitch2.Items = {'0', '1'};
            app.LockSwitch2.ValueChangedFcn = createCallbackFcn(app, @LockSwitch2ValueChanged, true);
            app.LockSwitch2.Value = '0';
            app.LockSwitchLabel_3 = uilabel(app.UIFigure);
            app.LockSwitchLabel_3.Text = 'Lock';
            app.LockSwitch3 = uiswitch(app.UIFigure, 'slider');
            app.LockSwitch3.Items = {'0', '1'};
            app.LockSwitch3.ValueChangedFcn = createCallbackFcn(app, @LockSwitch3ValueChanged, true);
            app.LockSwitch3.Value = '0';
            app.LockSwitchLabel_4 = uilabel(app.UIFigure);
            app.LockSwitchLabel_4.Text = 'Lock';
            app.LockSwitch4 = uiswitch(app.UIFigure, 'slider');
            app.LockSwitch4.Items = {'0', '1'};
            app.LockSwitch4.ValueChangedFcn = createCallbackFcn(app, @LockSwitch4ValueChanged, true);
            app.LockSwitch4.Value = '0';
            app.LockSwitchLabel_5 = uilabel(app.UIFigure);
            app.LockSwitchLabel_5.Text = 'Lock';
            app.LockSwitch5 = uiswitch(app.UIFigure, 'slider');
            app.LockSwitch5.Items = {'0', '1'};
            app.LockSwitch5.ValueChangedFcn = createCallbackFcn(app, @LockSwitch5ValueChanged, true);
            app.LockSwitch5.Value = '0';
            app.LockSwitchLabel_6 = uilabel(app.UIFigure);
            app.LockSwitchLabel_6.Text = 'Lock';
            app.LockSwitch6 = uiswitch(app.UIFigure, 'slider');
            app.LockSwitch6.Items = {'0', '1'};
            app.LockSwitch6.ValueChangedFcn = createCallbackFcn(app, @LockSwitch6ValueChanged, true);
            app.LockSwitch6.Value = '0';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Player1'; 'Player2'};
            app.UITable.RowName = {};
            app.ScoreEditFieldLabel = uilabel(app.UIFigure);
            app.ScoreEditFieldLabel.Text = 'Score';
            app.ScoreEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScoreEditField_2Label = uilabel(app.UIFigure);
            app.ScoreEditField_2Label.Text = 'Score';
            app.ScoreEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Cover = uiimage(app.UIFigure);
            app.Cover.ImageSource = 'cover.jpg';
            app.CoverRollAgain_1 = uiimage(app.UIFigure);
            app.CoverRollAgain_1.ImageSource = 'cover_rollagain.jpg';
            app.CoverRollAgain_2 = uiimage(app.UIFigure);
            app.CoverRollAgain_2.ImageSource = 'cover_rollagain.jpg';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'DICE GAME'};
            app.Image_win1 = uiimage(app.UIFigure);
            app.Image_win1.Visible = 'off';
            app.Image_win1.ImageSource = 'win.jpg';
            app.Image_win2 = uiimage(app.UIFigure);
            app.Image_win2.Visible = 'off';
            app.Image_win2.ImageSource = 'win.jpg';
            app.RulesAndScoringPanel = uipanel(app.UIFigure);
            app.DaRulesLabel = uilabel(app.RulesAndScoringPanel);
            app.DaRulesLabel.Text = 'Da Rules';
            app.Label = uilabel(app.RulesAndScoringPanel);
            app.Label.Text = '';
            app.RulesText = uitextarea(app.RulesAndScoringPanel);
            app.RulesText.Value = {'Each player has three turns. During a turn, you roll the dice five times.'; ''; 'You may lock in any scoring dice and roll again.'; ''; 'For example, if you roll 1-2-2-5-5-6 on your turn, you could set aside the 1 and the two 5''s for scoring, or you could choose to set aside only the 1.'; ''; 'You may also end your turn by clicking the reset button.'; ''; 'The player with the highest score after their three turns wins.'};
            app.ScoringGuidelinesLabel = uilabel(app.RulesAndScoringPanel);
            app.ScoringGuidelinesLabel.Text = 'Scoring Guidelines';
            app.Label_2 = uilabel(app.RulesAndScoringPanel);
            app.Label_2.Text = '';
            app.ScoringGuidelinesText = uitextarea(app.RulesAndScoringPanel);
            app.ScoringGuidelinesText.Value = {'1                                                                     100 points'; '5	                                                               50 points'; 'Three 1''s	                                                       1,000 points'; 'Three 2''s                                                         200 points'; 'Three 3''s	                                                        300 points'; 'Three 4''s	                                                        400 points'; 'Three 5''s	                                                        500 points'; 'Three 6''s	                                                        600 points'; '1-2-3-4-5-6 (Straight)	                                3000 points'; '3 Pairs (including 4-of-a-kind and a pair)	1500 points '};
            app.ContinueButton_2 = uibutton(app.RulesAndScoringPanel, 'push');
            app.ContinueButton_2.ButtonPushedFcn = createCallbackFcn(app, @ContinueButton_2Pushed, true);
            app.ContinueButton_2.Text = 'Continue';
            app.WelcomePanel = uipanel(app.RulesAndScoringPanel);
            app.DiceGameLabel = uilabel(app.WelcomePanel);
            app.DiceGameLabel.Text = 'Dice Game';
            app.Player1EditFieldLabel = uilabel(app.WelcomePanel);
            app.Player1EditFieldLabel.Text = 'Player 1';
            app.Player1EditField = uieditfield(app.WelcomePanel, 'text');
            app.Player1EditField.ValueChangedFcn = createCallbackFcn(app, @Player1EditFieldValueChanged, true);
            app.Player2EditFieldLabel = uilabel(app.WelcomePanel);
            app.Player2EditFieldLabel.Text = 'Player 2';
            app.Player2EditField = uieditfield(app.WelcomePanel, 'text');
            app.Player2EditField.ValueChangedFcn = createCallbackFcn(app, @Player2EditFieldValueChanged, true);
            app.ContinueButton = uibutton(app.WelcomePanel, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.Image7 = uiimage(app.WelcomePanel);
            app.Image7.ImageClickedFcn = createCallbackFcn(app, @Image7Clicked, true);
            app.Image7.ImageSource = 'mickey.png';
            app.Image8 = uiimage(app.WelcomePanel);
            app.Image8.ImageClickedFcn = createCallbackFcn(app, @Image8Clicked, true);
            app.Image8.ImageSource = 'minnie.jpeg';
            app.Image9 = uiimage(app.WelcomePanel);
            app.Image9.ImageClickedFcn = createCallbackFcn(app, @Image9Clicked, true);
            app.Image9.ImageSource = 'donald.png';
            app.Image10 = uiimage(app.WelcomePanel);
            app.Image10.ImageClickedFcn = createCallbackFcn(app, @Image10Clicked, true);
            app.Image10.ImageSource = 'daisy.png';
            app.Player1Label = uilabel(app.WelcomePanel);
            app.Player1Label.Text = 'Player 1: ';
            app.Player2Label = uilabel(app.WelcomePanel);
            app.Player2Label.Text = 'Player 2: ';
            app.PickyourcharacterLabel = uilabel(app.WelcomePanel);
            app.PickyourcharacterLabel.Text = 'Pick your character';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_Dice_App_updated

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end